classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectAllCheckBox               matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        DefaultWindowTypeDropDown       matlab.ui.control.DropDown
        DefaultWindowTypeDropDownLabel  matlab.ui.control.Label
        NumberofFFTPointsEditField      matlab.ui.control.EditField
        NumberofFFTPointsEditFieldLabel  matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.EditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        OverlappingEditField            matlab.ui.control.EditField
        OverlappingEditFieldLabel       matlab.ui.control.Label
        HolaXDLabel                     matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_1                      matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function numericArray = validateCommaSepInput(app, inputValue, fieldName)
            if ischar(inputValue) || isstring(inputValue)
                numericArray = str2double(split(inputValue, ','));
            else
                numericArray = inputValue;
            end
        
            if isempty(numericArray) || any(isnan(numericArray)) || ~isnumeric(numericArray)
                app.Label.Text = [fieldName, ' must be a comma-separated numbers.'];
                numericArray = -1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectAllCheckBox
        function CheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            if value
                for i = 1:29
                    app.(['CheckBox_' num2str(i)]).Value = true;
                end
            else 
                for i = 1:29
                    app.(['CheckBox_' num2str(i)]).Value = false;
                end
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            channels = [];
            for i = 1:29
                if app.(['CheckBox_' num2str(i)]).Value
                    channels = [channels, i];
                end
            end

            if isempty(channels)
                app.Label.Text = 'No channels selected. Please select at least one channel.';
                return
            end

            N_FFT = validateCommaSepInput(app, app.NumberofFFTPointsEditField.Value, 'N_FFT');
            if N_FFT == -1
                return
            end

           
            overlapping = validateCommaSepInput(app, app.OverlappingEditField.Value, 'Overlapping');
            if overlapping == -1
                return
            end
            for i = 1:length(overlapping)
                if overlapping(i) < 0 || overlapping(i) > 1
                    app.Label.Text = 'Overlap must be a value between 0 and 1.';
                    return
                end            
            end


            window_size = validateCommaSepInput(app, app.WindowSizeEditField.Value, 'Window Size');
            if window_size == -1
                return
            end

            default_window = app.DefaultWindowTypeDropDown.Value;
            % Validate Default Window
            if ~ischar(default_window) || isempty(default_window)
                app.Label.Text = 'Default Window must be a valid non-empty string.';
                return
            end

            app.Label.Text = '';

            DSPA1(channels, N_FFT, overlapping, window_size, default_window);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.UIFigure);
            app.CheckBox_10.Text = '10';
            app.CheckBox_11 = uicheckbox(app.UIFigure);
            app.CheckBox_11.Text = '11';
            app.CheckBox_12 = uicheckbox(app.UIFigure);
            app.CheckBox_12.Text = '12';
            app.CheckBox_13 = uicheckbox(app.UIFigure);
            app.CheckBox_13.Text = '13';
            app.CheckBox_14 = uicheckbox(app.UIFigure);
            app.CheckBox_14.Text = '14';
            app.CheckBox_15 = uicheckbox(app.UIFigure);
            app.CheckBox_15.Text = '15';
            app.CheckBox_16 = uicheckbox(app.UIFigure);
            app.CheckBox_16.Text = '16';
            app.CheckBox_17 = uicheckbox(app.UIFigure);
            app.CheckBox_17.Text = '17';
            app.CheckBox_18 = uicheckbox(app.UIFigure);
            app.CheckBox_18.Text = '18';
            app.CheckBox_19 = uicheckbox(app.UIFigure);
            app.CheckBox_19.Text = '19';
            app.CheckBox_20 = uicheckbox(app.UIFigure);
            app.CheckBox_20.Text = '20';
            app.CheckBox_21 = uicheckbox(app.UIFigure);
            app.CheckBox_21.Text = '21';
            app.CheckBox_22 = uicheckbox(app.UIFigure);
            app.CheckBox_22.Text = '22';
            app.CheckBox_23 = uicheckbox(app.UIFigure);
            app.CheckBox_23.Text = '23';
            app.CheckBox_24 = uicheckbox(app.UIFigure);
            app.CheckBox_24.Text = '24';
            app.CheckBox_25 = uicheckbox(app.UIFigure);
            app.CheckBox_25.Text = '25';
            app.CheckBox_26 = uicheckbox(app.UIFigure);
            app.CheckBox_26.Text = '26';
            app.CheckBox_27 = uicheckbox(app.UIFigure);
            app.CheckBox_27.Text = '27';
            app.CheckBox_28 = uicheckbox(app.UIFigure);
            app.CheckBox_28.Text = '28';
            app.CheckBox_29 = uicheckbox(app.UIFigure);
            app.CheckBox_29.Text = '29';
            app.ChannelsLabel = uilabel(app.UIFigure);
            app.ChannelsLabel.Text = 'Channels';
            app.HolaXDLabel = uilabel(app.UIFigure);
            app.HolaXDLabel.Text = 'Hola XD';
            app.OverlappingEditFieldLabel = uilabel(app.UIFigure);
            app.OverlappingEditFieldLabel.Text = 'Overlapping';
            app.OverlappingEditField = uieditfield(app.UIFigure, 'text');
            app.WindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel.Text = 'window size';
            app.WindowSizeEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofFFTPointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFFTPointsEditFieldLabel.Text = 'Number of FFT Points';
            app.NumberofFFTPointsEditField = uieditfield(app.UIFigure, 'text');
            app.DefaultWindowTypeDropDownLabel = uilabel(app.UIFigure);
            app.DefaultWindowTypeDropDownLabel.Text = 'Default Window Type';
            app.DefaultWindowTypeDropDown = uidropdown(app.UIFigure);
            app.DefaultWindowTypeDropDown.Items = {'Rectangular', 'Triangular', 'Hamming', 'Blackman'};
            app.DefaultWindowTypeDropDown.Value = 'Rectangular';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SelectAllCheckBox = uicheckbox(app.UIFigure);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end